classdef perturbationSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout4                 matlab.ui.container.GridLayout
        DisplayDropDown             matlab.ui.control.DropDown
        DisplayDropDownLabel        matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        PerturbationSettingsExpressedinBodyFrameLabel  matlab.ui.control.Label
        EnableButtonsPanel          matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        MiscellaneousPerturbationSwitchLabel_2  matlab.ui.control.Label
        OrbitPerturbationSwitch     matlab.ui.control.Switch
        MiscellaneousPerturbationSwitch  matlab.ui.control.Switch
        MiscellaneousPerturbationSwitchLabel  matlab.ui.control.Label
        ConstantPerturbationSwitch  matlab.ui.control.Switch
        ConstantPerturbationSwitchLabel  matlab.ui.control.Label
        uipanel2                    matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        text7                       matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text10                      matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        w3_edit                     matlab.ui.control.NumericEditField
        w2_edit                     matlab.ui.control.NumericEditField
        w1_edit                     matlab.ui.control.NumericEditField
        A_edit                      matlab.ui.control.NumericEditField
        uipanel1                    matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        z_text                      matlab.ui.control.Label
        y_text                      matlab.ui.control.Label
        x_text                      matlab.ui.control.Label
        Tgz_edit                    matlab.ui.control.NumericEditField
        Tgy_edit                    matlab.ui.control.NumericEditField
        Tgx_edit                    matlab.ui.control.NumericEditField
        perturbation_text           matlab.ui.control.Label
        UIAxes_tggz                 matlab.ui.control.UIAxes
        UIAxes_tggy                 matlab.ui.control.UIAxes
        UIAxes_tggx                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callMainApp             % window property
        time                    % Time vector
        total_torque            % Torque control
        gravity_torque          % Torque control
        drag_torque             % Torque control
        third_body_sun_torque   % Torque control
        third_body_moon_torque  % Torque control
        solar_torque            % Torque control
    end
    
    methods (Access = private)
        
        function updateInterface(app, data_t, data)
            %%% 1. Synchronize Constant Disturbance Fields
            % Maps static torque components to the UI edit fields
            app.Tgx_edit.Value = data.constant.Tdx;
            app.Tgy_edit.Value = data.constant.Tdy;
            app.Tgz_edit.Value = data.constant.Tdz;
        
            %%% 2. Synchronize Variable Disturbance Fields
            % Maps oscillation parameters for dynamic perturbations
            app.A_edit.Value  = data.variable.A;
            app.w1_edit.Value = data.variable.w1;
            app.w2_edit.Value = data.variable.w2;
            app.w3_edit.Value = data.variable.w3;
        
            %%% 3. Update Switch States (Logic Enables)
            % ValueIndex is used to toggle between ON (1) and OFF (0)
            app.ConstantPerturbationSwitch.ValueIndex      = data.constant.enable;
            app.MiscellaneousPerturbationSwitch.ValueIndex = data.variable.enable;
           
            %%% 4. Buffer Simulation Variables
            % Store current time and torque matrices in the app object for global access
            app.time.t = data_t.t;
            app.time.nSteps = data_t.nSteps;
            app.total_torque = data.torque.total;
        
            %%% 5. Graphical Update
            % Update Selector (use public method from ADCS_program.mlapp)
            app.total_torque = getDisturbances(app.callMainApp, data, app.time);
            % Execute the external plotting function to refresh the UI Axes
            my_plot_axis(app, app.time, app.total_torque.total)
        end    
        
        function newData = updateParameters(app)
            %%%Update constant disturbances
            newData.disturbances.constant.Tdx = app.Tgx_edit.Value;
            newData.disturbances.constant.Tdy = app.Tgy_edit.Value;
            newData.disturbances.constant.Tdz = app.Tgz_edit.Value;
            %%%Update miscellaneous disturbances
            newData.disturbances.variable.A  = app.A_edit.Value;
            newData.disturbances.variable.w1 = app.w1_edit.Value;
            newData.disturbances.variable.w2 = app.w2_edit.Value; 
            newData.disturbances.variable.w3 = app.w3_edit.Value; 
            %%%Read enable button
            newData.disturbances.constant.enable = app.ConstantPerturbationSwitch.ValueIndex;
            newData.disturbances.variable.enable = app.MiscellaneousPerturbationSwitch.ValueIndex;
            newData.disturbances.model_propagator.enable = app.OrbitPerturbationSwitch.ValueIndex;
            newData.gui_identifier = 2;
        end

        function my_plot_axis(app, time, data)
            % Define the target UIAxes handles as the first argument for all operations
            axes_list = [app.UIAxes_tggx, app.UIAxes_tggy, app.UIAxes_tggz];
            labels = {'X', 'Y', 'Z'};
            
            for i = 1:3
                ax = axes_list(i);
                
                % 1. Specify 'ax' as the first argument in plot
                plot(ax, time.t, data(i, :));
                
                % 2. Specify 'ax' in formatting functions
                xlabel(ax, 'time (s)'); 
                ylabel(ax, sprintf('Torque in %s (Nm)', labels{i}));
                title(ax, '');      
                grid(ax, 'on');     
                
                % --- Auto-scale Logic ---
                % 'tight' ensures the limits match the data range exactly
                axis(ax, 'tight'); 
                
                % 'auto' allows the axes to update limits dynamically if new data is added
                ax.XLimMode = 'auto';
                ax.YLimMode = 'auto';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData_time, mainAppData_dis)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData_time, mainAppData_dis);
        end

        % Callback function
        function constPer_pushbuttonButtonPushed(app, event)
            %dataToSend = updateParameters(app);
            %importData(app.callMainApp, dataToSend);
            %delete(app);
        end

        % Callback function
        function miscPer_pushbuttonButtonPushed(app, event)

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            data = app.updateParameters();
            app.total_torque = getDisturbances(app.callMainApp, data.disturbances, app.time);
            my_plot_axis(app, app.time, app.total_torque.total);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dataToSend = app.updateParameters();
            importData(app.callMainApp, dataToSend);
            delete(app);
        end

        % Value changed function: OrbitPerturbationSwitch
        function OrbitPerturbationSwitchValueChanged(app, event)
            value = app.OrbitPerturbationSwitch.Value;
            if strcmp(value, 'On')

                app.MiscellaneousPerturbationSwitch.Enable = 'Off';
                app.ConstantPerturbationSwitch.Enable = 'Off';
                
                app.Tgx_edit.Enable = 'Off';
                app.Tgy_edit.Enable = 'Off';
                app.Tgz_edit.Enable = 'Off';

                app.A_edit.Enable = 'Off';
                app.w1_edit.Enable = 'Off';
                app.w2_edit.Enable = 'Off';
                app.w3_edit.Enable = 'Off';

            else

                app.MiscellaneousPerturbationSwitch.Enable = 'On';
                app.ConstantPerturbationSwitch.Enable = 'On';

                app.Tgx_edit.Enable = 'On';
                app.Tgy_edit.Enable = 'On';
                app.Tgz_edit.Enable = 'On';

                app.A_edit.Enable = 'On';
                app.w1_edit.Enable = 'On';
                app.w2_edit.Enable = 'On';
                app.w3_edit.Enable = 'On';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.UIAxes_tggx = uiaxes(app.GridLayout4);
            title(app.UIAxes_tggx, 'Title')
            xlabel(app.UIAxes_tggx, 'X')
            ylabel(app.UIAxes_tggx, 'Y')
            zlabel(app.UIAxes_tggx, 'Z')
            app.UIAxes_tggy = uiaxes(app.GridLayout4);
            title(app.UIAxes_tggy, 'Title')
            xlabel(app.UIAxes_tggy, 'X')
            ylabel(app.UIAxes_tggy, 'Y')
            zlabel(app.UIAxes_tggy, 'Z')
            app.UIAxes_tggz = uiaxes(app.GridLayout4);
            title(app.UIAxes_tggz, 'Title')
            xlabel(app.UIAxes_tggz, 'X')
            ylabel(app.UIAxes_tggz, 'Y')
            zlabel(app.UIAxes_tggz, 'Z')
            app.uipanel1 = uipanel(app.GridLayout4);
            app.uipanel1.Title = 'Constant Perturbation';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout = uigridlayout(app.uipanel1);
            app.perturbation_text = uilabel(app.GridLayout);
            app.perturbation_text.Tag = 'text6';
            app.perturbation_text.Text = 'Perturbation (Nm):';
            app.Tgx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgx_edit.ValueDisplayFormat = '%.2E';
            app.Tgy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgy_edit.ValueDisplayFormat = '%.2E';
            app.Tgz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgz_edit.ValueDisplayFormat = '%.2E';
            app.x_text = uilabel(app.GridLayout);
            app.x_text.Tag = 'text6';
            app.x_text.Text = 'X';
            app.y_text = uilabel(app.GridLayout);
            app.y_text.Tag = 'text6';
            app.y_text.Text = 'Y';
            app.z_text = uilabel(app.GridLayout);
            app.z_text.Tag = 'text6';
            app.z_text.Text = 'Z';
            app.uipanel2 = uipanel(app.GridLayout4);
            app.uipanel2.Title = 'Miscellaneous Perturbation';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout2 = uigridlayout(app.uipanel2);
            app.A_edit = uieditfield(app.GridLayout2, 'numeric');
            app.A_edit.ValueDisplayFormat = '%.E';
            app.w1_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w1_edit.ValueDisplayFormat = '%.E';
            app.w2_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w2_edit.ValueDisplayFormat = '%.E';
            app.w3_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w3_edit.ValueDisplayFormat = '%.E';
            app.text8 = uilabel(app.GridLayout2);
            app.text8.Tag = 'text8';
            app.text8.Text = '[ sin(';
            app.text10 = uilabel(app.GridLayout2);
            app.text10.Tag = 'text10';
            app.text10.Text = 't), sin(';
            app.text12 = uilabel(app.GridLayout2);
            app.text12.Tag = 'text12';
            app.text12.Text = 't), sin(';
            app.text13 = uilabel(app.GridLayout2);
            app.text13.Tag = 'text13';
            app.text13.Text = 't)] ''';
            app.text7 = uilabel(app.GridLayout2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Perturbation (Nm):';
            app.EnableButtonsPanel = uipanel(app.GridLayout4);
            app.EnableButtonsPanel.Title = 'Enable Buttons';
            app.GridLayout3 = uigridlayout(app.EnableButtonsPanel);
            app.ConstantPerturbationSwitchLabel = uilabel(app.GridLayout3);
            app.ConstantPerturbationSwitchLabel.Text = 'Constant Perturbation';
            app.ConstantPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.MiscellaneousPerturbationSwitchLabel = uilabel(app.GridLayout3);
            app.MiscellaneousPerturbationSwitchLabel.Text = 'Miscellaneous Perturbation';
            app.MiscellaneousPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.OrbitPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.OrbitPerturbationSwitch.ValueChangedFcn = createCallbackFcn(app, @OrbitPerturbationSwitchValueChanged, true);
            app.MiscellaneousPerturbationSwitchLabel_2 = uilabel(app.GridLayout3);
            app.MiscellaneousPerturbationSwitchLabel_2.Text = 'Orbital Perturbations';
            app.PerturbationSettingsExpressedinBodyFrameLabel = uilabel(app.GridLayout4);
            app.PerturbationSettingsExpressedinBodyFrameLabel.Text = 'Perturbation Settings (Expressed in Body Frame)';
            app.ApplyButton = uibutton(app.GridLayout4, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DisplayDropDownLabel = uilabel(app.GridLayout4);
            app.DisplayDropDownLabel.Text = 'Display:';
            app.DisplayDropDown = uidropdown(app.GridLayout4);
            app.DisplayDropDown.Items = {'Total torque', 'Gravity Gradient torque', 'Atmospheric Drag torque', 'Solar radiation pressure torque', 'Gravitational perturbation from the Sun', 'Gravitational perturbation from the Moon'};
            app.DisplayDropDown.Value = 'Total torque';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perturbationSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end